classdef GraficasCalidad < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        PorcentajedefalloLabel        matlab.ui.control.Label
        NiveldedificultadButtonGroup  matlab.ui.container.ButtonGroup
        Nivel4                        matlab.ui.control.RadioButton
        Nivel3                        matlab.ui.control.RadioButton
        Nivel2                        matlab.ui.control.RadioButton
        Nivel1                        matlab.ui.control.RadioButton
        GridLayout3                   matlab.ui.container.GridLayout
        ResultadosButton              matlab.ui.control.Button
        GridLayout2                   matlab.ui.container.GridLayout
        ComprobarButton               matlab.ui.control.Button
        StopButton                    matlab.ui.control.Button
        IniciarmovimientoButton       matlab.ui.control.Button
        FMuneca                       matlab.ui.control.UIAxes
        SCodo                         matlab.ui.control.UIAxes
        AHombro                       matlab.ui.control.UIAxes
        FHombro                       matlab.ui.control.UIAxes
        AMuneca                       matlab.ui.control.UIAxes
        FCodo                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        rango = 9; % Description
        pintar = 0; % Description
        contador = 0;
    end
    
    methods (Access = private)
        
        function readData(app,src,~)
            data = readline(src);
data=split(data);
data =str2double(data);

src.UserData.Data(size(src.UserData.Data,1)+1,:)= data;

src.UserData.Count = src.UserData.Count + 1;

if src.UserData.Count > 100001
    configureCallback(src,"off");
    plot(app.scr.UserData.Data(2:end));
%     plot(app.SCodo.scr.UserData.Data(2:end));
%     plot(app.AHombro.scr.UserData.Data(2:end));
%     plot(app.FHombro.scr.UserData.Data(2:end));
%     plot(app.AMuneca.scr.UserData.Data(2:end));
end
        end
        function [mSUP, mINF] = RangosCalidadApp ( app, yIdeal, ejex, rango)

%Grafica ideal
%y = [10,14,19,26,31,33,30,26,21,16,10];

x = 0:0.1:1;
%Rangos
rSUP = yIdeal + rango;
rINF= yIdeal - rango;

xR = 0:0.001:1;

%makima rango SUP
    ppSUP= makima(x*ejex,rSUP);
    mSUP= ppval(ppSUP, xR*ejex);

%makima rango INF
     ppINF= makima(x*ejex,rINF);
     mINF= ppval(ppINF, xR*ejex);
    
 
   % plot(xR*duration,mSUP, 'r'), hold on, plot(xR*duration,mINF, 'r')
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarmovimientoButton
        function IniciarmovimientoButtonPushed(app, event)
           
            app.pintar = 1;
            %tiempo de duracion del movimiento
           maxim= 4;
           minim= 1;
           duration = minim+(maxim-minim)*rand(1);


            % value = app.TiempoTotal.Value - duration;
      [xnHF,ynHF,yHFF,xHFF] = AleatoriedadFHombro(app,1, duration,'cl');
      [xnHA,ynHA,yHAF,xHAF] = AleatoriedadAbdHombro(app,1, duration,'cl');  
      [xnCF,ynCF,yCFF,xCFF] = AleatoriedadFCodo(app,1, duration,'cl');
      [xnCS,ynCS,yCSF,xCSF] = AleatoriedadSCodo(app,1, duration,'cl');
      [xnMF,ynMF,yMFF,xMFF] = AleatoriedadFMuneca(app,1, duration,'cl');
      [xnMA,ynMA,yMAF,xMAF] = AleatoriedadAbdMuneca(app,1,duration,'cl');
      
      reset(app.FMuneca),reset(app.SCodo),reset(app.AHombro),reset(app.FHombro),reset(app.AMuneca),reset(app.FCodo)
     
     % ejex=3000;
      
      %HA
      
       
       muestras = 3900;
x1 = 1:5 :muestras;
x2 = 1:5 :muestras;
x3 = 1:5 :muestras;
x4 = 1:5 :muestras;
x5 = 1:5 :muestras;
x6 = 1:5 :muestras;

 %Pintamos los rangos


puerto = serialport("COM7",115200);
configureTerminator(puerto,"CR/LF",1);
puerto.UserData = struct('Data',[],'Count',1);
readline(puerto)
write(puerto, 's','string')
for i = 1:40
    readline(puerto);
   
end
flush(puerto);
puerto.UserData.Data=[];
i = 1;


while(app.pintar == 1)
    readData(app,puerto);
     
x = puerto.UserData.Data(:,1);
    plot(x1(1:i),x(1:i),'r');
drawnow;

%  x = puerto.UserData.Data(:,1);
%      plot(app.FMuneca,x2(1:i),x(1:i),'r');
% drawnow;
% 
% x = puerto.UserData.Data(:,1);
%     plot(app.FCodo,x3(1:i),x(1:i),'r');
% drawnow;
% 
 y = puerto.UserData.Data(:,2);
%  plot(app.AMuneca,x4(1:i),y(1:i),'g');
% drawnow;
% 
% y = puerto.UserData.Data(:,2);
%  plot(app.AHombro,x5(1:i),y(1:i),'g');
% drawnow;
% 
 z = puerto.UserData.Data(:,3);
%  plot(app.SCodo,x6(1:i),z(1:i),'b');
% drawnow;
    i=i+1;
end

  comprobar = 1;
            if (comprobar ~= 0)
                app.contador = app.contador +1;
            end
            if (app.pintar == 0)
                filename = 'PruebasCalidad.xlsx';

                xlswrite(filename,x,app.contador,'A')
                xlswrite(filename,y,app.contador,'B')
                xlswrite(filename,z,app.contador,'C')


xr =length(x);
yr = length(y);
zr = length(z);

  [xnHF,ynHF,yHFF,xHFF] = AleatoriedadFHombro(app,1, xr,'cl');
      [xnHA,ynHA,yHAF,xHAF] = AleatoriedadAbdHombro(app,1, yr,'cl');  
      [xnCF,ynCF,yCFF,xCFF] = AleatoriedadFCodo(app,1, xr,'cl');
      [xnCS,ynCS,yCSF,xCSF] = AleatoriedadSCodo(app,1, zr,'cl');
%       [xnMF,ynMF,yMFF,xMFF] = AleatoriedadFMuneca(app,1, duration,'cl');
%       [xnMA,ynMA,yMAF,xMAF] = AleatoriedadAbdMuneca(app,1,duration,'cl');
      

 [mSUP, mINF]=  RangosCalidadApp ( app, [10,14,19,26,31,33,30,26,21,16,10], yr, app.rango);
       rHAS = mSUP;
       rHAI = mINF; 
     
      %MA
%       [mSUP, mINF]=  RangosCalidad ( app, [5,6,10,12,15,16,15,12,9,6,5], ejex, app.rango);
%        rMAS = mSUP;
%        rMAI = mINF; 
      %CF
      [mSUP, mINF]=  RangosCalidadApp ( app, [67,70,89,108,115,120,115,107,83,72,67], xr, app.rango);
       rCFS = mSUP;
       rCFI = mINF;
      %HF
      [mSUP, mINF]=  RangosCalidadApp ( app, [40,42,45,50,56,60,56,50,45,42,40], xr, app.rango);
       rHFS = mSUP;
       rHFI = mINF;
      %MF
%       [mSUP, mINF]=  RangosCalidad ( app, [27,25,23,20,21,20,22,20,23,25,27], ejex, app.rango);
%        rMFS = mSUP;
%        rMFI = mINF;
      %SC
      [mSUP, mINF]=  RangosCalidadApp ( app, [25,28,35,42,52,51,54,25,17,10,25], zr, app.rango);
       rCSS = mSUP;
       rCSI = mINF;


       %Pintar Rangos
        plot(app.FHombro,xHFF,rHFS,'--r'), hold (app.FHombro),
       plot(app.FHombro,xHFF,rHFI,'--r'),
       plot(app.AHombro,xHAF,rHAS,'--r'), hold (app.AHombro),
       plot(app.AHombro,xHAF,rHAI,'--r'),
       plot(app.SCodo,xCSF,rCSS,'--r'), hold (app.SCodo),
       plot(app.SCodo,xCSF,rCSI,'--r')
% %        plot(app.FMuneca,xMFF,rMFS,'--r'), hold (app.FMuneca),
% %       plot(app.FMuneca,xMFF,rMFI,'--r'),
% %        plot(app.AMuneca,xMAF,rMAS,'--r'), hold (app.AMuneca),
% %        plot(app.AMuneca,xMAF,rMAI,'--r'),
       plot(app.FCodo,xCFF,rCFS,'--r'), hold (app.FCodo),
       plot(app.FCodo,xCFF,rCFI,'--r')

                       [num,txt,raw] =    xlsread(filename,app.contador)
                plot(app.FHombro,num(:,1)), hold on;
                plot(app.AHombro,num(:,2)), hold on;
                plot(app.FCodo,num(:,1)), hold on;%SEGUNDO SENSOR
                plot(app.SCodo,num(:,3)), hold on; %SEGUNDO SENSOR


            end

%x = puerto.UserData.Data(:,1);
%y = puerto.UserData.Data(:,2);
%z = puerto.UserData.Data(:,3);

%{
%plot(app.FCodo,x)
%hold on
plot(app.FMuneca,x)
hold on
%plot(app.FHombro,x)
hold on
plot(app.AMuneca,y,'r')
hold on
plot(app.AHombro,y,'r')
hold on
plot(app.SCodo,z,'g')
%}

%{
for i = 1:n
plot(app.FHombro,x1(1:i),x(1:i),'-r');
drawnow;

plot(app.FMuneca,x1(1:i),x(1:i),'-r');
drawnow;

plot(app.FCodo,x1(1:i),x(1:i),'-r');
drawnow;

plot(app.AMuneca,x1(1:i),x(1:i),'-r');
drawnow;

plot(app.AHombro,x1(1:i),x(1:i),'-r');
drawnow;

plot(app.FHombro,x1(1:i),x(1:i),'-r');
drawnow;
end
%}      
       
       maxsize=max([length(yHAF),length(yHFF),length(yMAF),length(yMFF),length(yCFF),length(yCSF)]);
    i=1;
    %{
       while i<muestras
        if i<length(muestras)
            plot(app.FHombro,muestras(1:i),x(1:i))
        end
        if i<length(yHAF)
            plot(app.AHombro,xHAF(1:i),yHAF(1:i))
        end
        if i<length(yCFF)
            plot(app.FCodo,xCFF(1:i),yCFF(1:i))
        end
        if i<length(yCSF)
            plot(app.SCodo,xCSF(1:i),yCSF(1:i))
        end
       % if i<length(yMFF)
        %    plot(app.FMuneca,xMFF(1:i),yMFF(1:i))
       % end
        if i<length(yMAF)
            plot(app.AMuneca,xMAF(1:i),yMAF(1:i))
        end
        i=i+30;
        drawnow
        pause(0.01)
       end
    %}
%Comparamos el eje y con los rangos para ver que no se ha salido
    j = 1;
    fHF = 0;
    fHA = 0;
    fCF = 0;
    fCS = 0;
    fMF = 0;
    fMA = 0;
    
        while j < maxsize
        if j<length(yHFF)
            if yHFF(j) < rHFS(j) %Comparamos con rango superior
                if yHFF(j) > rHFI(j) %Comparamos con rango inferior
                   fHF = fHF+0 ;%no se suma fallo
                else
                    fHF = fHF+1;
                end
            else 
                fHF = fHF+1; %Se suma fallo
            end
               
        end
        if j<length(yHAF)
             if yHAF(j) < rHAS(j) %Comparamos con rango superior
                if yHAF(j) > rHAI(j) %Comparamos con rango inferior
                   fHA = fHA+0 ;%no se suma fallo
                else
                    fHA = fHA+1;
                end
            else 
                fHA = fHA+1; %Se suma fallo
             end
        end
        if j<length(yCFF)
             if yCFF(j) < rCFS(j) %Comparamos con rango superior
                if yCFF(j) > rCFI(j) %Comparamos con rango inferior
                   fCF = fCF+0 ;%no se suma fallo
                else
                    fCF = fCF+1;
                end
            else 
                fCF = fCF+1; %Se suma fallo
             end
        end
        if j<length(yCSF)
             if yCSF(j) < rCSS(j) %Comparamos con rango superior
                if yCSF(j) > rCSI(j) %Comparamos con rango inferior
                   fCS = fCS+0 ;%no se suma fallo
                else
                    fCS = fCS+1;
                end
            else 
                fCS = fCS+1; %Se suma fallo
             end
        end
%         if j<length(yMFF)
%              if yMFF(j) < rMFS(j) %Comparamos con rango superior
%                 if yMFF(j) > rMFI(j) %Comparamos con rango inferior
%                    fMF = fMF+0 ;%no se suma fallo
%                 else
%                     fMF = fMF+1;
%                 end
%             else 
%                 fMF = fMF+1; %Se suma fallo
%              end
%         end
%         if j<length(yMAF)
%              if yMAF(j) < rMAS(j) %Comparamos con rango superior
%                 if yMAF(j) > rMAI(j) %Comparamos con rango inferior
%                    fMA = fMA+0 ;%no se suma fallo
%                 else
%                     fMA = fMA+1;
%                 end
%             else 
%                 fMA = fMA+1; %Se suma fallo
%              end
%         end
        j=j+1;
        
        end
   
 %NUMERO DE FALLOS TOTALES E INDIVIDUALES
    fTotal = fMA + fMF + fCS + fCF + fHA + fHF;
    %
    % 
    % 
    % 
    % 
    % 
     
 ftotal100 = (fTotal *100) / (maxsize*6) ;
   % ftotal100 = (fTotal *100) / 6000 ;
    value = 'El porcentaje de fallo es del ' + string(ftotal100) + ' %';
    set(app.PorcentajedefalloLabel, 'Text', value);


%    

 T = table (fTotal,maxsize,fMA , fMF , fCS , fCF , fHA ,fHF);
                L = {'fTotal','maxsize','fMA' , 'fMF' , 'fCS' , 'fCF' , 'fHA' ,'fHF'};
                C = table2cell(T);
                %Concateno en la matriz cell la legenda de cada columna
                A = [L;C];
                xlswrite('ProgresoCalidad.xlsx',A,'xlswrite','A2');

fMA100 = (fMA * 100) / 1000;
fMF100 = (fMF * 100) / 1000;
fCS100 = (fCS * 100) / 1000;
fCF100 = (fCF * 100) / 1000;
fHA100 = (fHA * 100) / 1000;
fHF100 = (fHF * 100) / 1000;


TfHF = table (fHF100);
                LfHF = {'Fallo'};
                CfHF = table2cell(TfHF);
               % Concateno en la matriz cell la legenda de cada columna
                AfHF = [LfHF;CfHF];
                xlswrite('DatosInformeCalidad.xlsx',CfHF,'Hoja1','E2');
         
TfHA = table (fHA100);
                LfHA = {'Fallo'};
                CfHA = table2cell(TfHA);
               % Concateno en la matriz cell la legenda de cada columna
                AfHA = [LfHA;CfHA];
                xlswrite('DatosInformeCalidad.xlsx',CfHA,'Hoja1','E3');

TfCF = table (fCF100);
                LfCF = {'Fallo'};
                CfCF = table2cell(TfCF);
               % Concateno en la matriz cell la legenda de cada columna
                AfCF = [LfCF;CfCF];
                xlswrite('DatosInformeCalidad.xlsx',CfCF,'Hoja1','E4');

TfCS = table (fCS100);
                LfCS = {'Fallo'};
                CfCS = table2cell(TfCS);
               % Concateno en la matriz cell la legenda de cada columna
                AfCS = [LfCS;CfCS];
                xlswrite('DatosInformeCalidad.xlsx',CfCS,'Hoja1','E5');

 TfMA = table (fMA100);
                LfMA = {'Fallo'};
                CfMA = table2cell(TfMA);
               % Concateno en la matriz cell la legenda de cada columna
                AfMA = [LfMA;CfMA];
                xlswrite('DatosInformeCalidad.xlsx',CfMA,'Hoja1','E6');

 TfMF = table (fMF100);
                LfMF = {'Fallo'};
                CfMF = table2cell(TfMF);
               % Concateno en la matriz cell la legenda de cada columna
                AfMF = [LfMF;CfMF];
                xlswrite('DatosInformeCalidad.xlsx',CfMF,'Hoja1','E7');


 %-----------------------------------------               
  %Escribir en un excel


ID = 1;
%MAXIMOS  
maximoHF = max (yHFF);
maximoHA = max(yHAF);
maximoCS = max(yCSF);
maximoCF = max(yCFF);
maximoMF = max(yMFF);
maximoMA = max(yMAF);

TmaximoHF = table (maximoHF);
                LmaximoHF = {'Maximo'};
                CmaximoHF = table2cell(TmaximoHF);
               % Concateno en la matriz cell la legenda de cada columna
                AmaximoHF = [LmaximoHF;CmaximoHF];
                xlswrite('DatosInformeCalidad.xlsx',CmaximoHF,'Hoja1','G2');
         
TmaximoHA = table (maximoHA);
                LmaximoHA = {'Maximo'};
                CmaximoHA = table2cell(TmaximoHA);
               % Concateno en la matriz cell la legenda de cada columna
                AmaximoHA = [LmaximoHA;CmaximoHA];
                xlswrite('DatosInformeCalidad.xlsx',CmaximoHA,'Hoja1','G3');

TmaximoCF = table (maximoCF);
                LmaximoCF = {'Maximo'};
                CmaximoCF = table2cell(TmaximoCF);
               % Concateno en la matriz cell la legenda de cada columna
                AmaximoCF = [LmaximoCF;CmaximoCF];
                xlswrite('DatosInformeCalidad.xlsx',CmaximoCF,'Hoja1','G4');

TmaximoCS = table (maximoCS);
                LmaximoCS = {'Maximo'};
                CmaximoCS = table2cell(TmaximoCS);
               % Concateno en la matriz cell la legenda de cada columna
                AmaximoCS = [LmaximoCS;CmaximoCS];
                xlswrite('DatosInformeCalidad.xlsx',CmaximoCS,'Hoja1','G5');

 TmaximoMA = table (maximoMA);
                LmaximoMA = {'Maximo'};
                CmaximoMA = table2cell(TmaximoMA);
               % Concateno en la matriz cell la legenda de cada columna
                AmaximoMA = [LmaximoMA;CmaximoMA];
                xlswrite('DatosInformeCalidad.xlsx',CmaximoMA,'Hoja1','G6');

 TmaximoMF = table (maximoMF);
                LmaximoMF = {'Maximo'};
                CmaximoMF = table2cell(TmaximoMF);
               % Concateno en la matriz cell la legenda de cada columna
                AmaximoMF = [LmaximoMF;CmaximoMF];
                xlswrite('DatosInformeCalidad.xlsx',CmaximoMF,'Hoja1','G7');


%MINIMOS  
minimoHF = min(yHFF);
minimoHA = min(yHAF);
minimoCS = min(yCSF);
minimoCF = min(yCFF);
minimoMF = min(yMFF);
minimoMA = min(yMAF);

TminimoHF = table (minimoHF);
                LminimoHF = {'Minimo'};
                CminimoHF = table2cell(TminimoHF);
               % Concateno en la matriz cell la legenda de cada columna
                AminimoHF = [LminimoHF;CminimoHF];
                xlswrite('DatosInformeCalidad.xlsx',CminimoHF,'Hoja1','H2');
         
TminimoHA = table (minimoHA);
                LminimoHA = {'Minimo'};
                CminimoHA = table2cell(TminimoHA);
               % Concateno en la matriz cell la legenda de cada columna
                AminimoHA = [LminimoHA;CminimoHA];
                xlswrite('DatosInformeCalidad.xlsx',CminimoHA,'Hoja1','H3');

TminimoCF = table (minimoCF);
                LminimoCF = {'Minimo'};
                CminimoCF = table2cell(TminimoCF);
               % Concateno en la matriz cell la legenda de cada columna
                AminimoCF = [LminimoCF;CminimoCF];
                xlswrite('DatosInformeCalidad.xlsx',CminimoCF,'Hoja1','H4');

TminimoCS = table (minimoCS);
                LminimoCS = {'Minimo'};
                CminimoCS = table2cell(TminimoCS);
               % Concateno en la matriz cell la legenda de cada columna
                AminimoCS = [LminimoCS;CminimoCS];
                xlswrite('DatosInformeCalidad.xlsx',CminimoCS,'Hoja1','H5');

 TminimoMA = table (minimoMA);
                LminimoMA = {'Minimo'};
                CminimoMA = table2cell(TminimoMA);
               % Concateno en la matriz cell la legenda de cada columna
                AminimoMA = [LminimoMA;CminimoMA];
                xlswrite('DatosInformeCalidad.xlsx',CminimoMA,'Hoja1','H6');

 TminimoMF = table (minimoMF);
                LminimoMF = {'Minimo'};
                CminimoMF = table2cell(TminimoMF);
               % Concateno en la matriz cell la legenda de cada columna
                AminimoMF = [LminimoMF;CminimoMF];
                xlswrite('DatosInformeCalidad.xlsx',CminimoMF,'Hoja1','H7');

%RANGOS 
rangoHF = max (yHFF) - min(yHFF);
rangoHA = max (yHAF) - min(yHAF);
rangoCS = max (yCSF) - min(yCSF);
rangoCF = max (yCFF) - min(yCFF);
rangoMF = max (yMFF) - min(yMFF);
rangoMA = max (yMAF) - min(yMAF);

TrangoHF = table (rangoHF);
                LrangoHF = {'Rango'};
                CrangoHF = table2cell(TrangoHF);
               % Concateno en la matriz cell la legenda de cada columna
                ArangoHF = [LrangoHF;CrangoHF];
                xlswrite('DatosInformeCalidad.xlsx',CrangoHF,'Hoja1','F2');
         
TrangoHA = table (rangoHA);
                LrangoHA = {'Rango'};
                CrangoHA = table2cell(TrangoHA);
               % Concateno en la matriz cell la legenda de cada columna
                ArangoHA = [LrangoHA;CrangoHA];
                xlswrite('DatosInformeCalidad.xlsx',CrangoHA,'Hoja1','F3');

TrangoCF = table (rangoCF);
                LrangoCF = {'Rango'};
                CrangoCF = table2cell(TrangoCF);
               % Concateno en la matriz cell la legenda de cada columna
                ArangoCF = [LrangoCF;CrangoCF];
                xlswrite('DatosInformeCalidad.xlsx',CrangoCF,'Hoja1','F4');

TrangoCS = table (rangoCS);
                LrangoCS = {'Rango'};
                CrangoCS = table2cell(TrangoCS);
               % Concateno en la matriz cell la legenda de cada columna
                ArangoCS = [LrangoCS;CrangoCS];
                xlswrite('DatosInformeCalidad.xlsx',CrangoCS,'Hoja1','F5');

 TrangoMA = table (rangoMA);
                LrangoMA = {'Rango'};
                CrangoMA = table2cell(TrangoMA);
               % Concateno en la matriz cell la legenda de cada columna
                ArangoMA = [LrangoMA;CrangoMA];
                xlswrite('DatosInformeCalidad.xlsx',CrangoMA,'Hoja1','F6');

 TrangoMF = table (rangoMF);
                LrangoMF = {'Rango'};
                CrangoMF = table2cell(TrangoMF);
               % Concateno en la matriz cell la legenda de cada columna
                ArangoMF = [LrangoMF;CrangoMF];
                xlswrite('DatosInformeCalidad.xlsx',CrangoMF,'Hoja1','F7');


% ID Y MOVIMIENTO 

                T = table (ID,  ftotal100);
                L = {'ID','Fallo Total'};
                C = table2cell(T);
               % Concateno en la matriz cell la legenda de cada columna
                A = [L;C;C;C;C;C;C];
                xlswrite('DatosInformeCalidad.xlsx',C,'Hoja1','C2');
                xlswrite('DatosInformeCalidad.xlsx',C,'Hoja1','C3');
                xlswrite('DatosInformeCalidad.xlsx',C,'Hoja1','C4');
                xlswrite('DatosInformeCalidad.xlsx',C,'Hoja1','C5');
                xlswrite('DatosInformeCalidad.xlsx',C,'Hoja1','C6');
                xlswrite('DatosInformeCalidad.xlsx',C,'Hoja1','C7');


        end

        % Button pushed function: ResultadosButton
        function ResultadosButtonPushed(app, event)
            Calidad;
            closereq ();
        end

        % Selection changed function: NiveldedificultadButtonGroup
        function NiveldedificultadButtonGroupSelectionChanged(app, event)
            selectedButton = app.NiveldedificultadButtonGroup.SelectedObject;
            
            if selectedButton == app.Nivel1 
                app.rango = 9;
            elseif selectedButton == app.Nivel2 
                app.rango = 7;
            elseif selectedButton == app.Nivel3 
                app.rango = 5;
            elseif selectedButton == app.Nivel4 
                app.rango = 3;
            else
                app.rango=9;
            end


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.pintar=0;
        
        end

        % Button pushed function: ComprobarButton
        function ComprobarButtonPushed(app, event)
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.FCodo = uiaxes(app.GridLayout);
            title(app.FCodo, {'Flexión Codo'; ''})
            xlabel(app.FCodo, 'X')
            ylabel(app.FCodo, 'Y')
            zlabel(app.FCodo, 'Z')
            app.AMuneca = uiaxes(app.GridLayout);
            title(app.AMuneca, {'Abducción Muñeca'; ''})
            xlabel(app.AMuneca, 'X')
            ylabel(app.AMuneca, 'Y')
            zlabel(app.AMuneca, 'Z')
            app.FHombro = uiaxes(app.GridLayout);
            title(app.FHombro, {'Flexión Hombro'; ''})
            xlabel(app.FHombro, 'X')
            ylabel(app.FHombro, 'Y')
            zlabel(app.FHombro, 'Z')
            app.AHombro = uiaxes(app.GridLayout);
            title(app.AHombro, {'Abduccióm Hombro'; ''})
            xlabel(app.AHombro, 'X')
            ylabel(app.AHombro, 'Y')
            zlabel(app.AHombro, 'Z')
            app.SCodo = uiaxes(app.GridLayout);
            title(app.SCodo, {'Supinación Codo'; ''})
            xlabel(app.SCodo, 'X')
            ylabel(app.SCodo, 'Y')
            zlabel(app.SCodo, 'Z')
            app.FMuneca = uiaxes(app.GridLayout);
            title(app.FMuneca, 'Flexión Muñeca')
            xlabel(app.FMuneca, 'X')
            ylabel(app.FMuneca, 'Y')
            zlabel(app.FMuneca, 'Z')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.IniciarmovimientoButton = uibutton(app.GridLayout2, 'push');
            app.IniciarmovimientoButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarmovimientoButtonPushed, true);
            app.IniciarmovimientoButton.Text = {'Iniciar movimiento'; ''};
            app.StopButton = uibutton(app.GridLayout2, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = {'Stop'; ''};
            app.ComprobarButton = uibutton(app.GridLayout2, 'push');
            app.ComprobarButton.ButtonPushedFcn = createCallbackFcn(app, @ComprobarButtonPushed, true);
            app.ComprobarButton.Text = {'Comprobar'; ''};
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.ResultadosButton = uibutton(app.GridLayout3, 'push');
            app.ResultadosButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadosButtonPushed, true);
            app.ResultadosButton.Text = {'Resultados'; ''};
            app.NiveldedificultadButtonGroup = uibuttongroup(app.GridLayout);
            app.NiveldedificultadButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NiveldedificultadButtonGroupSelectionChanged, true);
            app.NiveldedificultadButtonGroup.Title = 'Nivel de dificultad';
            app.Nivel1 = uiradiobutton(app.NiveldedificultadButtonGroup);
            app.Nivel1.Text = '1';
            app.Nivel1.Value = true;
            app.Nivel2 = uiradiobutton(app.NiveldedificultadButtonGroup);
            app.Nivel2.Text = '2';
            app.Nivel3 = uiradiobutton(app.NiveldedificultadButtonGroup);
            app.Nivel3.Text = '3';
            app.Nivel4 = uiradiobutton(app.NiveldedificultadButtonGroup);
            app.Nivel4.Text = '4';
            app.PorcentajedefalloLabel = uilabel(app.GridLayout);
            app.PorcentajedefalloLabel.Text = 'Porcentaje de fallo:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GraficasCalidad

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end