classdef Informe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        CantidadTab              matlab.ui.container.Tab
        Numero                   matlab.ui.control.Label
        PruebaLabel              matlab.ui.control.Label
        Avanzar                  matlab.ui.control.Button
        Volver                   matlab.ui.control.Button
        VolverButton_3           matlab.ui.control.Button
        ActualizarDatosButton    matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        TiempoTab                matlab.ui.container.Tab
        VolverButton_2           matlab.ui.control.Button
        ActualizarDatosButton_2  matlab.ui.control.Button
        UITable_2                matlab.ui.control.Table
        CalidadTab               matlab.ui.container.Tab
        VolverButton             matlab.ui.control.Button
        ActualizarDatosButton_3  matlab.ui.control.Button
        UITable_3                matlab.ui.control.Table
    end

    
    properties (Access = private)
        contador; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.contador = 1;
             value =  string(app.contador) ;
    set(app.Numero, 'Text', value);
        end

        % Button pushed function: ActualizarDatosButton
        function ActualizarDatosButtonPushed(app, event)
            [num,txt,raw] =    xlsread('DatosInformeCantidad2.xlsx',app.contador)
            
            C1 = num(:,1);
            C2 = num(:,2);
            C3= num(:,3);
            C4= num(:,4);
            C5= num(:,5);

            xlswrite('SacadorDatosCantidad.xlsx',C1, 'A1:A5')
            xlswrite('SacadorDatosCantidad.xlsx',C2, 'B1:B5')
            xlswrite('SacadorDatosCantidad.xlsx',C3, 'C1:C5')
            xlswrite('SacadorDatosCantidad.xlsx',C4, 'D1:D5')
            xlswrite('SacadorDatosCantidad.xlsx',C5, 'E1:E5')

            
            datos  = readtable('SacadorDatosCantidad.xlsx');
            app.UITable.Data = datos;
            columnas = datos.Properties.VariableNames;
            app.UITable.ColumnName = columnas;
        end

        % Button pushed function: ActualizarDatosButton_2
        function ActualizarDatosButton_2Pushed(app, event)
            datos  = readtable('DatosInformeTiempo2.xlsx',app.contador);
            app.UITable_2.Data = datos;
            columnas = datos.Properties.VariableNames;
            app.UITable.ColumnName = columnas;
        end

        % Button pushed function: ActualizarDatosButton_3
        function ActualizarDatosButton_3Pushed(app, event)
            datos  = readtable('DatosInformeCalidad2.xlsx',app.contador);
            app.UITable_3.Data = datos;
            columnas = datos.Properties.VariableNames;
            app.UITable.ColumnName = columnas;
        end

        % Button pushed function: VolverButton_3
        function VolverButton_3Pushed(app, event)
            Progreso;
             closereq();
        end

        % Button pushed function: VolverButton_2
        function VolverButton_2Pushed(app, event)
            Progreso;
             closereq();
        end

        % Button pushed function: VolverButton
        function VolverButtonPushed(app, event)
            Progreso;
             closereq();
        end

        % Button pushed function: Volver
        function VolverPushed(app, event)
            comprobar = 1;
            if comprobar ~= 0
                app.contador = app.contador - 1;
            end
            if app.contador < 1
                app.contador = 1;
            end
                         value =  string(app.contador) ;
    set(app.Numero, 'Text', value);

        end

        % Button down function: CantidadTab
        function CantidadTabButtonDown(app, event)
            
        end

        % Button pushed function: Avanzar
        function AvanzarButtonPushed(app, event)
             comprobar = 1;
            if comprobar ~= 0
                app.contador = app.contador + 1;
            end
                         value =  string(app.contador) ;
    set(app.Numero, 'Text', value);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CantidadTab = uitab(app.TabGroup);
            app.CantidadTab.Title = 'Cantidad ';
            app.CantidadTab.ButtonDownFcn = createCallbackFcn(app, @CantidadTabButtonDown, true);
            app.UITable = uitable(app.CantidadTab);
            app.UITable.ColumnName = {'Métrica'; 'Movimiento'; 'ID'; 'Número de Movimientos'; 'Rango de Movimiento'; 'Máximo'; 'Minimo'};
            app.UITable.RowName = {};
            app.ActualizarDatosButton = uibutton(app.CantidadTab, 'push');
            app.ActualizarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarDatosButtonPushed, true);
            app.ActualizarDatosButton.Text = 'Actualizar Datos';
            app.VolverButton_3 = uibutton(app.CantidadTab, 'push');
            app.VolverButton_3.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_3Pushed, true);
            app.VolverButton_3.Text = 'Volver';
            app.Volver = uibutton(app.CantidadTab, 'push');
            app.Volver.ButtonPushedFcn = createCallbackFcn(app, @VolverPushed, true);
            app.Volver.Text = '<<<<<';
            app.Avanzar = uibutton(app.CantidadTab, 'push');
            app.Avanzar.ButtonPushedFcn = createCallbackFcn(app, @AvanzarButtonPushed, true);
            app.Avanzar.Text = {'>>>>>'; ''};
            app.PruebaLabel = uilabel(app.CantidadTab);
            app.PruebaLabel.Text = 'Prueba:';
            app.Numero = uilabel(app.CantidadTab);
            app.Numero.Text = '';
            app.TiempoTab = uitab(app.TabGroup);
            app.TiempoTab.Title = 'Tiempo ';
            app.UITable_2 = uitable(app.TiempoTab);
            app.UITable_2.ColumnName = {'Métrica'; 'Movimiento'; 'ID'; 'Tiempo'; 'Rango de Movimiento'; 'Máximo'; 'Minimo'};
            app.UITable_2.RowName = {};
            app.ActualizarDatosButton_2 = uibutton(app.TiempoTab, 'push');
            app.ActualizarDatosButton_2.ButtonPushedFcn = createCallbackFcn(app, @ActualizarDatosButton_2Pushed, true);
            app.ActualizarDatosButton_2.Text = 'Actualizar Datos';
            app.VolverButton_2 = uibutton(app.TiempoTab, 'push');
            app.VolverButton_2.ButtonPushedFcn = createCallbackFcn(app, @VolverButton_2Pushed, true);
            app.VolverButton_2.Text = 'Volver';
            app.CalidadTab = uitab(app.TabGroup);
            app.CalidadTab.Title = 'Calidad';
            app.UITable_3 = uitable(app.CalidadTab);
            app.UITable_3.ColumnName = {'Métrica'; 'Movimiento'; 'ID'; 'Porcentaje de Fallo Total'; 'Porcentajes de Fallo'; 'Rango de Movimiento'; 'Máximo'; 'Minimo'};
            app.UITable_3.RowName = {};
            app.ActualizarDatosButton_3 = uibutton(app.CalidadTab, 'push');
            app.ActualizarDatosButton_3.ButtonPushedFcn = createCallbackFcn(app, @ActualizarDatosButton_3Pushed, true);
            app.ActualizarDatosButton_3.Text = 'Actualizar Datos';
            app.VolverButton = uibutton(app.CalidadTab, 'push');
            app.VolverButton.ButtonPushedFcn = createCallbackFcn(app, @VolverButtonPushed, true);
            app.VolverButton.Text = {'Volver'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Informe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end